classdef ImageProcess < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button_3  matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
        Button    matlab.ui.control.Button
        UIAxes2   matlab.ui.control.UIAxes
        UIAxes    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            msgbox('这是一个图像处理小demo','关于','help');
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            choice = questdlg('是否关闭','关闭','YES','NO','NO');
            switch choice 
                case 'YES'
                    delete(app.UIFigure);
                case 'NO'
                    return
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            [filename,filepath] = uigetfile('*.*');
            if isequal(filename,0) || isequal(filepath,0)
                errordlg('未选中图片','错误');
            else
                fileroute = strcat(filepath,filename);
                im = imread(fileroute);
                imgray = rgb2gray(im);
                imshow(im,'Parent',app.UIAxes);
                axis(app.UIAxes,'off');
                imagesc(app.UIAxes2,imgray);
                colormap(app.UIAxes2,'gray');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '原始图像')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '灰度图像')
            zlabel(app.UIAxes2, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Tooltip = {'提示信息，但我不提示'};
            app.Button.Text = '退出程序';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Tooltip = {'提示信息，但我不提示'};
            app.Button_2.Text = '关于';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Tooltip = {'提示信息，但我不提示'};
            app.Button_3.Text = '打开图像';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageProcess

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end