classdef buttonGroup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        kEditField        matlab.ui.control.NumericEditField
        kEditFieldLabel   matlab.ui.control.Label
        nEditField        matlab.ui.control.NumericEditField
        nEditFieldLabel   matlab.ui.control.Label
        wlEditField       matlab.ui.control.NumericEditField
        wlEditFieldLabel  matlab.ui.control.Label
        addButton         matlab.ui.control.Button
        Button            matlab.ui.control.Button
        UITable           matlab.ui.control.Table
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        t % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonGroupSelectionChanged(app, event)

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.t = readtable('C:\Users\20841\Desktop\Au.csv');
            app.UITable.Data = app.t;
            app.UITable.ColumnName = app.t.Properties.VariableNames;
            wavelength = app.t.Wavelength__m;
            n = app.t.n;
            k = app.t.k;
            plot(app.UIAxes,wavelength,n);
            hold(app.UIAxes,'on');
            plot(app.UIAxes,wavelength,k);
            app.UIAxes.XLabel.String= 'wavelength';
            app.UIAxes.YLabel.String = 'n';
        end

        % Button pushed function: addButton
        function addButtonPushed(app, event)
            wavelength = app.wlEditField.Value;
            class(wavelength)
            n = app.nEditField.Value;
            k = app.kEditField.Value;
            newdata = {wavelength n k}
            temp = app.t
%             app.UITable.Data = {app.t;newdata};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.addButton = uibutton(app.UIFigure, 'push');
            app.addButton.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.addButton.Text = 'add';
            app.wlEditFieldLabel = uilabel(app.UIFigure);
            app.wlEditFieldLabel.Text = 'wl';
            app.wlEditField = uieditfield(app.UIFigure, 'numeric');
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.UIFigure, 'numeric');
            app.kEditFieldLabel = uilabel(app.UIFigure);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = buttonGroup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end