classdef lamp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        EditField         matlab.ui.control.NumericEditField
        EditFieldLabel    matlab.ui.control.Label
        Lamp              matlab.ui.control.Lamp
        LampLabel_2       matlab.ui.control.Label
        Warn              matlab.ui.control.EditField
        EditField_2Label  matlab.ui.control.Label
        Temp              matlab.ui.control.EditField
        Label             matlab.ui.control.Label
        LampLabel         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Temp
        function TempValueChanged(app, event)
            value = str2double(app.Temp.Value);
            if value >= 500
                app.Warn.Value = '温度过高';
                app.Lamp.Color = [1 0 0];
            elseif value < 300
                app.Warn.Value = '温度过低';
                app.Lamp.Color = [0 0 1];
            else
                app.Warn.Value = '温度适中';
                app.Lamp.Color = [0 1 0];
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            if value >= 500
                app.Warn.Value = '温度过高';
                app.Lamp.Color = [1 0 0];
            elseif value < 300
                app.Warn.Value = '温度过低';
                app.Lamp.Color = [0 0 1];
            else
                app.Warn.Value = '温度适中';
                app.Lamp.Color = [0 1 0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '当前温度';
            app.Temp = uieditfield(app.UIFigure, 'text');
            app.Temp.ValueChangedFcn = createCallbackFcn(app, @TempValueChanged, true);
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '提示信息';
            app.Warn = uieditfield(app.UIFigure, 'text');
            app.LampLabel_2 = uilabel(app.UIFigure);
            app.LampLabel_2.Text = '提示灯';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Tooltip = {'T>=500：温度过高'; 'T<=300：温度过低'; '其余：正常'};
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lamp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end